classdef MusicGenreClassifierApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MusicGenreClassifier       matlab.ui.Figure
        MusicGenrePredictionLabel  matlab.ui.control.Label
        EstimatedGenreLabel        matlab.ui.control.Label
        LoadWAVFileButton          matlab.ui.control.Button
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        Model              % Your trained model
        AudioData          % Variable to store loaded audio data
    end
    
    methods (Access = private)
        function predictGenre(app, features)
            try
                % Apply Quadratic KNN
                [~, scores] = app.Model.trainedModel.predictFcn(features(:));
        
                % Apply softmax to convert scores into probabilities
                probabilities = exp(scores) / sum(exp(scores));

                % Customize genre comparisons based on your genre labels
                genreLabels = {'Blues', 'Classical', 'Country', 'Disco', 'Hiphop'};
        
                % Find the index with the highest probability
                [~, predictedIdx] = max(probabilities);
                
                % Calculate the accuracy as percent
                accuracyPercentage = ((max(probabilities)) * 100);

                % Display the estimated genre only if the probability is above the threshold
                if probabilities(predictedIdx)
                    app.EstimatedGenreLabel.Text = ['Estimated Genre: ' genreLabels{predictedIdx}, '  ', int2str(accuracyPercentage), '%'];
                else
                    app.EstimatedGenreLabel.Text = 'Estimated Genre: Unknown';
                end
            catch exception
                % Display any exception for debugging
                disp('Error in predictGenre:');
                disp(getReport(exception));
                app.EstimatedGenreLabel.Text = 'Error predicting genre';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Model = load('matlab.mat');
        end

        % Button pushed function: LoadWAVFileButton
        function LoadWAVFileButtonPushed(app, event)
            % Open dialog to select a WAV file
            [filename, filepath] = uigetfile('*.wav', 'Select WAV File');

            if filename == 0
                return; % User canceled the operation
            end

            % Read the audio file
            [audio, ~] = audioread(fullfile(filepath, filename));

            % Store audio data in the app property
            app.AudioData = audio;
        
            % Apply spectrogram to the audio
            spectrogramData = abs(spectrogram(audio, kaiser(2048, 128), 120, 16, 1E3, 'yaxis'));
            spectrogramData = spectrogramData(:);
        
            % Ensure that the feature matrix has the correct size (3087 rows)
            features = zeros(1, 3087);
        
            if length(spectrogramData) <= 3087
                features(1:length(spectrogramData)) = spectrogramData.';
            else
                features = spectrogramData(1:3087).';
            end
        
            % Predict genre
            predictGenre(app, features);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MusicGenreClassifier = uifigure('Visible', 'off');
            app.MusicGenreClassifier.Name = 'MATLAB App';
            app.Image = uiimage(app.MusicGenreClassifier);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'v904-nunny-010-f.jpg');
            app.LoadWAVFileButton = uibutton(app.MusicGenreClassifier, 'push');
            app.LoadWAVFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWAVFileButtonPushed, true);
            app.LoadWAVFileButton.Icon = fullfile(pathToMLAPP, 'icons8-start-90.png');
            app.LoadWAVFileButton.Text = 'Load WAV File';
            app.EstimatedGenreLabel = uilabel(app.MusicGenreClassifier);
            app.EstimatedGenreLabel.Text = 'Estimated Genre:';
            app.MusicGenrePredictionLabel = uilabel(app.MusicGenreClassifier);
            app.MusicGenrePredictionLabel.Text = 'Music Genre Prediction';
            % Show the figure after all components are created
            app.MusicGenreClassifier.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MusicGenreClassifierApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MusicGenreClassifier)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MusicGenreClassifier)
        end
    end
end